classdef dianya < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
%         EditFieldNumber matlab.ui.control.NumericEditField % Description
%         Label matlab.ui.control.Label
        CallingApp
        xuhao % Description
    end
    
    methods (Access = public)
        
        function updateVoltage(app,dianya)   %刷新表格，显示当前电压
            [~,N]=size(dianya);
            dianya=round(dianya,2);
            Data=horzcat(app.xuhao,dianya);
            a=reshape(Data',N,2*N)';
            b=string(a);
            app.UITable.Data=b;
%%%%%不使用表格，而是使用可输入文本框
%             for i=1:N
%                 for j=1:N
%                     k=(i-1)*N+j;                  
%                     app.Label(k) = uilabel(app.UIFigure);
%                     app.Label(k).HorizontalAlignment = 'right';
%                     app.Label(k).Position = [11+(j-1)*33 459-(i-1)*30 30 15];
%                     app.Label(k).Text = num2str(k);
%                     app.EditFieldNumber(k) = uieditfield(app.UIFigure, 'numeric');
%                     app.EditFieldNumber(k).Limits=[-20 50];
%                     %app.EditFieldNumber(N).ValueChangedFcn = createCallbackFcn(app, @EditFieldNumberValueChanged, true);
%                     app.EditFieldNumber(k).Position = [11+(j-1)*33 444-(i-1)*30 30 17];
%                 end
%             end
%%%%%            
%%%%%把每个单元的编号放在该单元的左侧，而不是上方
%             app.UITable.ColumnWidth=num2cell(ones(1,2*N)*35);
%             a=zeros(1,2*N);
%             for i=1:N
%                 a(i*2)=1;
%             end
%             a=logical(a);
%             app.UITable.ColumnEditable=a;                        
%             app.UITable.Data=zeros(N,2*N);
%             for i=1:N
%                 app.UITable.Data(:,i*2-1)=(i-1)+(1:N:N^2-N+1);
%                 app.UITable.Data(:,i*2)=dianya(:,i);
%             end            
%%%%%            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dianya)
            app.CallingApp=mainapp;
            [~,N]=size(dianya);
            zhenlie=(1:N*N);            
            app.xuhao=reshape(zhenlie,N,N)';
            app.UITable.ColumnWidth=num2cell(ones(1,N)*35);
            updateVoltage(app,dianya)            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ButtonDianya.Enable='on';
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if mod(indices(1),2)==1
            else
                updateDianya(app.CallingApp,indices(1)/2,indices(2),newData)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dianya(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end