classdef phase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = public)
        
        function updatePhase(app,x,y,z)
            h=findobj(app.UIAxes,'Type','Surface');
            delete(h)
            hold(app.UIAxes,'on')
            pcolor(app.UIAxes,x,y,z)
            shading(app.UIAxes,'interp')
            colormap(app.UIAxes,'jet')
            app.UIAxes.Children=flipud(app.UIAxes.Children);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, axisx, axisy, x, y, z, aperture)
            app.CallingApp=mainapp;
            N=length(axisx);            
            pitch=axisx(2)-axisx(1);
            width_qishi=axisx(1)-pitch/2;            
            height_qishi=axisy(1)-pitch/2;            
            axis(app.UIAxes,'ij');
            axis(app.UIAxes,'equal');
            xlim(app.UIAxes,[width_qishi,width_qishi+N*pitch])
            ylim(app.UIAxes,[height_qishi,height_qishi+N*pitch])
            %%%%%画驱动器格子
            app.UIAxes.XTick=width_qishi:pitch:width_qishi+N*pitch;
            app.UIAxes.YTick=height_qishi:pitch:height_qishi+N*pitch;
            grid(app.UIAxes,'on')            
            %%%%%
            %%%%%每个格子写上驱动器编号
            xt=repmat(axisx,1,N);
            yt=repelem(axisy,N);
            actuator=(1:N*N);
            zt=string(actuator);
            text(app.UIAxes,xt,yt,zt,'HorizontalAlignment','center','FontSize',10)
            %%%%%

%             for i=1:35
%                 for j=1:35
%                     rectangle(app.UIAxes,'position',[width_qishi+(j-1)*pitch+pitch-0.1*pitch,height_qishi+(i-1)*pitch+pitch-0.1*pitch,0.5e-3,0.5e-3],'curvature',[1 1],'FaceColor','[1 0 0]')
%                 end
%             end

            %aperture=80e-3;
            %aperture=50e-3;
%             rectangle(app.UIAxes,'position',[-aperture/2,-aperture/2,aperture,aperture],'curvature',[1 1])%画一个100mm口径的圆
            if length(z)==1
            else
                updatePhase(app,x,y,z)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ButtonPhase.Enable='on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end