classdef region < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = public)
        
        function updateRegion(app,axisx,axisy,ave)                                   
            N=length(axisx);
            xt=repmat(axisx,1,N);
            yt=repelem(axisy,N);
            ave=ave';
            ave=round(ave*1e9,1);
            zt=string(ave);
            text(app.UIAxes,xt,yt,zt,'HorizontalAlignment','center','FontSize',8.5)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, axisx, axisy, ave)
            app.CallingApp=mainapp;
            N=length(axisx);
            pitch=axisx(2)-axisx(1);
            width_qishi=axisx(1)-pitch/2;            
            height_qishi=axisy(1)-pitch/2;            
            axis(app.UIAxes,'ij');
            axis(app.UIAxes,'equal');
            xlim(app.UIAxes,[width_qishi,width_qishi+N*pitch])
            ylim(app.UIAxes,[height_qishi,height_qishi+N*pitch])
            %%%%%画驱动器格子
            app.UIAxes.XTick=width_qishi:pitch:width_qishi+N*pitch;
            app.UIAxes.YTick=height_qishi:pitch:height_qishi+N*pitch;
            grid(app.UIAxes,'on')            
            %%%%%
            if length(ave)==1
            else
                updateRegion(app,axisx,axisy,ave)
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ButtonRegion.Enable='on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = region(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end